classdef Data_Configuration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DatashouldbeendedwithlineENDLabel  matlab.ui.control.Label
        QuitSaveButton                matlab.ui.control.Button
        RestoreDefaultsButton         matlab.ui.control.Button
        VariablesonGraphsEditField    matlab.ui.control.EditField
        VariablesonGraphsEditFieldLabel  matlab.ui.control.Label
        IncaseofLabel                 matlab.ui.control.Label
        XY_1_1Y_1_2Y_1_kY_2_1Y_n_lLabel  matlab.ui.control.Label
        InsecondlineofSPCyouhavetospecifynamesofmeasuredvalueswithLabel  matlab.ui.control.Label
        NumberofGraphsEditField       matlab.ui.control.NumericEditField
        NumberofGraphsEditFieldLabel  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        DataConfigurationLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
          CallingApp % Main app 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, data)
            app.CallingApp = caller;
            Data = data;
            app.VariablesonGraphsEditField.Value =(strjoin(string(data.graphvariables),', '));
            app.NumberofGraphsEditField.Value = data.graphnum;
            guidata(app.UIFigure,Data);
        end

        % Value changed function: NumberofGraphsEditField
        function NumberofGraphsEditFieldValueChanged(app, event)
            value = app.NumberofGraphsEditField.Value;
            Data = guidata(app.UIFigure);
            Data.graphnum =value;
            guidata(app.UIFigure,Data);
        end

        % Value changed function: VariablesonGraphsEditField
        function VariablesonGraphsEditFieldValueChanged(app, event)
            value = app.VariablesonGraphsEditField.Value;
            Data = guidata(app.UIFigure);
            var = str2double(split(value,","));
            Data.graphvariables =var;
            guidata(app.UIFigure,Data);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Data = guidata(app.UIFigure);
            disp(Data);
            app.CallingApp.Data_main = Data;
            update_graphs(app.CallingApp,Data.graphnum)
            delete(app)
            
        end

        % Button pushed function: QuitSaveButton
        function QuitSaveButtonPushed(app, event)
            Data = guidata(app.UIFigure);
            disp(Data);
            app.CallingApp.Data_main = Data;
            update_graphs(app.CallingApp,Data.graphnum)
            delete(app)
        end

        % Button pushed function: RestoreDefaultsButton
        function RestoreDefaultsButtonPushed(app, event)
            app.NumberofGraphsEditField.Value = 1;
            app.VariablesonGraphsEditField.Value = "2";
            Data = guidata(app.UIFigure);
            Data.graphnum =1;
            Data.graphvariable = "2";
            guidata(app.UIFigure, Data);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DataConfigurationLabel = uilabel(app.UIFigure);
            app.DataConfigurationLabel.Text = 'Data Configuration';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'You are using application, which let you draw Graphs from data collected by'; 'Serial Port. To use it, you have to observe some rules:'; ''; '1. In first line of SPC you have to specify all titles for your graph. So now choose'; 'how many graphs do you want to draw (max 3):'; ''};
            app.NumberofGraphsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofGraphsEditFieldLabel.Text = 'Number of Graphs';
            app.NumberofGraphsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofGraphsEditField.Limits = [1 3];
            app.NumberofGraphsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofGraphsEditFieldValueChanged, true);
            app.NumberofGraphsEditField.Value = 1;
            app.InsecondlineofSPCyouhavetospecifynamesofmeasuredvalueswithLabel = uilabel(app.UIFigure);
            app.InsecondlineofSPCyouhavetospecifynamesofmeasuredvalueswithLabel.Text = {'2. In second line of SPC you have to specify names of Ylabels together with units in'; '[brackets]. In third line should be measured values. Data have to be arranged'; 'according to the scheme, where X is variable with time data and n is equal to '; 'number of Graphs:'};
            app.XY_1_1Y_1_2Y_1_kY_2_1Y_n_lLabel = uilabel(app.UIFigure);
            app.XY_1_1Y_1_2Y_1_kY_2_1Y_n_lLabel.Interpreter = 'tex';
            app.XY_1_1Y_1_2Y_1_kY_2_1Y_n_lLabel.Text = 'X, Y_{1_1}, Y_{1_2}, ... , Y_{1_k}, Y_{2_1}, ... , Y_{n_l}';
            app.IncaseofLabel = uilabel(app.UIFigure);
            app.IncaseofLabel.Text = {'3.In case of correct data analysis data on one graph should have same units type.'; 'User have to give number of variables (not including X) related to relevant graphs,'; 'too. It could be make by typing this separated commas in field below: '};
            app.VariablesonGraphsEditFieldLabel = uilabel(app.UIFigure);
            app.VariablesonGraphsEditFieldLabel.Text = 'Variables on Graphs';
            app.VariablesonGraphsEditField = uieditfield(app.UIFigure, 'text');
            app.VariablesonGraphsEditField.ValueChangedFcn = createCallbackFcn(app, @VariablesonGraphsEditFieldValueChanged, true);
            app.VariablesonGraphsEditField.Value = '2';
            app.RestoreDefaultsButton = uibutton(app.UIFigure, 'push');
            app.RestoreDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreDefaultsButtonPushed, true);
            app.RestoreDefaultsButton.Text = 'Restore Defaults';
            app.QuitSaveButton = uibutton(app.UIFigure, 'push');
            app.QuitSaveButton.ButtonPushedFcn = createCallbackFcn(app, @QuitSaveButtonPushed, true);
            app.QuitSaveButton.Text = 'Quit & Save';
            app.DatashouldbeendedwithlineENDLabel = uilabel(app.UIFigure);
            app.DatashouldbeendedwithlineENDLabel.Text = '4. Data should be ended with line "END".';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_Configuration(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end