classdef Serial_Port_Configuration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PortEditField                 matlab.ui.control.EditField
        PortEditFieldLabel            matlab.ui.control.Label
        MeasurmentstartedmanuallyCheckBox  matlab.ui.control.CheckBox
        SaveQuitButton                matlab.ui.control.Button
        RestoreDefaultsButton         matlab.ui.control.Button
        TimeoutEditField              matlab.ui.control.NumericEditField
        TimeoutEditFieldLabel         matlab.ui.control.Label
        TermintatorDropDown           matlab.ui.control.DropDown
        TermintatorDropDownLabel      matlab.ui.control.Label
        ByteOrderDropDown             matlab.ui.control.DropDown
        ByteOrderDropDownLabel        matlab.ui.control.Label
        FlowControlDropDown           matlab.ui.control.DropDown
        FlowControlDropDownLabel      matlab.ui.control.Label
        StopbitsDropDown              matlab.ui.control.DropDown
        StopbitsLabel                 matlab.ui.control.Label
        DatabitsDropDown              matlab.ui.control.DropDown
        DatabitsLabel                 matlab.ui.control.Label
        ParityDropDown                matlab.ui.control.DropDown
        ParityDropDownLabel           matlab.ui.control.Label
        BaudrateDropDown              matlab.ui.control.DropDown
        BaudrateDropDownLabel         matlab.ui.control.Label
        SerialPortConfigurationLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        CallingApp % Main app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, spc)
            % Creating SPC  struct and setting default
            app.CallingApp = caller;
            SPC = spc;
            app.TimeoutEditField.Value = SPC.timeout;
            app.PortEditField.Value = SPC.port;
            app.BaudrateDropDown.Value = string(SPC.baudrate);
            app.TermintatorDropDown.Value = SPC.terminator;
            app.ByteOrderDropDown.Value = SPC.byteorder;
            app.FlowControlDropDown.Value = SPC.flowcontrol;
            app.StopbitsDropDown.Value = string(SPC.stopbits);
            app.DatabitsDropDown.Value = string(SPC.databits);
            app.ParityDropDown.Value = SPC.parity;

            guidata(app.UIFigure,SPC)
        end

        % Value changed function: PortEditField
        function PortEditFieldValueChanged(app, event)
            value = app.PortEditField.Value;
            SPC = guidata(app.UIFigure);
            SPC.port = value;
            guidata(app.UIFigure,SPC);
        end

        % Button pushed function: SaveQuitButton
        function SaveQuitButtonPushed(app, event)
            SPC = guidata(app.UIFigure);
            app.CallingApp.SPC_main = SPC;
            delete(app);
        end

        % Button pushed function: RestoreDefaultsButton
        function RestoreDefaultsButtonPushed(app, event)
            app.TimeoutEditField.Value = 10;
            app.PortEditField.Value = "COM12";
            app.BaudrateDropDown.Value = app.BaudrateDropDown.Items(1);
            app.TermintatorDropDown.Value = app.TermintatorDropDown.Items(1);
            app.ByteOrderDropDown.Value = app.ByteOrderDropDown.Items(1);
            app.FlowControlDropDown.Value = app.FlowControlDropDown.Items(1);
            app.StopbitsDropDown.Value = app.StopbitsDropDown.Items(1);
            app.DatabitsDropDown.Value = app.DatabitsDropDown.Items(1);
            app.ParityDropDown.Value = app.ParityDropDown.Items(1);
        end

        % Value changed function: TimeoutEditField
        function TimeoutEditFieldValueChanged(app, event)
            value = app.TimeoutEditField.Value;
            SPC = guidata(app.UIFigure);
            SPC.timeout = value;
            guidata(app.UIFigure,SPC);
        end

        % Value changed function: TermintatorDropDown
        function TermintatorDropDownValueChanged(app, event)
            value = app.TermintatorDropDown.Value;
            SPC = guidata(app.UIFigure);
            SPC.terminator = value;
            guidata(app.UIFigure,SPC);
        end

        % Value changed function: ByteOrderDropDown
        function ByteOrderDropDownValueChanged(app, event)
            value = app.ByteOrderDropDown.Value;
            SPC = guidata(app.UIFigure);
            SPC.byteorder = value;
            guidata(app.UIFigure,SPC);
        end

        % Value changed function: FlowControlDropDown
        function FlowControlDropDownValueChanged(app, event)
            value = app.FlowControlDropDown.Value;
            SPC = guidata(app.UIFigure);
            SPC.flowcontrol = value;
            guidata(app.UIFigure,SPC);
        end

        % Value changed function: StopbitsDropDown
        function StopbitsDropDownValueChanged(app, event)
            value = app.StopbitsDropDown.Value;
            SPC = guidata(app.UIFigure);
            SPC.stopbits = str2double(value);
            guidata(app.UIFigure,SPC);
        end

        % Value changed function: DatabitsDropDown
        function DatabitsDropDownValueChanged(app, event)
            value = app.DatabitsDropDown.Value;
            SPC = guidata(app.UIFigure);
            SPC.databits = str2double(value);
            guidata(app.UIFigure,SPC);
        end

        % Value changed function: ParityDropDown
        function ParityDropDownValueChanged(app, event)
            value = app.ParityDropDown.Value;
            SPC = guidata(app.UIFigure);
            SPC.parity = value;
            guidata(app.UIFigure,SPC);
        end

        % Value changed function: BaudrateDropDown
        function BaudrateDropDownValueChanged(app, event)
            value = app.BaudrateDropDown.Value;
            SPC = guidata(app.UIFigure);
            SPC.baudrate = str2double(value);
            guidata(app.UIFigure,SPC);
        end

        % Value changed function: MeasurmentstartedmanuallyCheckBox
        function MeasurmentstartedmanuallyCheckBoxValueChanged(app, event)
            value = app.MeasurmentstartedmanuallyCheckBox.Value;
            SPC = guidata(app.UIFigure);
            SPC.meas = value;
            guidata(app.UIFigure,SPC);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SerialPortConfigurationLabel = uilabel(app.UIFigure);
            app.SerialPortConfigurationLabel.Text = 'Serial Port Configuration';
            app.BaudrateDropDownLabel = uilabel(app.UIFigure);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.BaudrateDropDown = uidropdown(app.UIFigure);
            app.BaudrateDropDown.Items = {'115200', '9600'};
            app.BaudrateDropDown.ValueChangedFcn = createCallbackFcn(app, @BaudrateDropDownValueChanged, true);
            app.BaudrateDropDown.Value = '115200';
            app.ParityDropDownLabel = uilabel(app.UIFigure);
            app.ParityDropDownLabel.Text = 'Parity';
            app.ParityDropDown = uidropdown(app.UIFigure);
            app.ParityDropDown.Items = {'none', 'even', 'odd'};
            app.ParityDropDown.ValueChangedFcn = createCallbackFcn(app, @ParityDropDownValueChanged, true);
            app.ParityDropDown.Value = 'none';
            app.DatabitsLabel = uilabel(app.UIFigure);
            app.DatabitsLabel.Text = 'Data bits';
            app.DatabitsDropDown = uidropdown(app.UIFigure);
            app.DatabitsDropDown.Items = {'8', '7', '6', '5'};
            app.DatabitsDropDown.ValueChangedFcn = createCallbackFcn(app, @DatabitsDropDownValueChanged, true);
            app.DatabitsDropDown.Value = '8';
            app.StopbitsLabel = uilabel(app.UIFigure);
            app.StopbitsLabel.Text = 'Stop bits';
            app.StopbitsDropDown = uidropdown(app.UIFigure);
            app.StopbitsDropDown.Items = {'1', '1.5', '2'};
            app.StopbitsDropDown.ValueChangedFcn = createCallbackFcn(app, @StopbitsDropDownValueChanged, true);
            app.StopbitsDropDown.Value = '1';
            app.FlowControlDropDownLabel = uilabel(app.UIFigure);
            app.FlowControlDropDownLabel.Text = 'Flow Control';
            app.FlowControlDropDown = uidropdown(app.UIFigure);
            app.FlowControlDropDown.Items = {'none', 'hardware', 'software'};
            app.FlowControlDropDown.ValueChangedFcn = createCallbackFcn(app, @FlowControlDropDownValueChanged, true);
            app.FlowControlDropDown.Value = 'none';
            app.ByteOrderDropDownLabel = uilabel(app.UIFigure);
            app.ByteOrderDropDownLabel.Text = 'Byte Order';
            app.ByteOrderDropDown = uidropdown(app.UIFigure);
            app.ByteOrderDropDown.Items = {'little-endian', 'big-endian'};
            app.ByteOrderDropDown.ValueChangedFcn = createCallbackFcn(app, @ByteOrderDropDownValueChanged, true);
            app.ByteOrderDropDown.Value = 'little-endian';
            app.TermintatorDropDownLabel = uilabel(app.UIFigure);
            app.TermintatorDropDownLabel.Text = 'Termintator';
            app.TermintatorDropDown = uidropdown(app.UIFigure);
            app.TermintatorDropDown.Items = {'CR/LF', 'CR', 'LF'};
            app.TermintatorDropDown.ValueChangedFcn = createCallbackFcn(app, @TermintatorDropDownValueChanged, true);
            app.TermintatorDropDown.Value = 'CR/LF';
            app.TimeoutEditFieldLabel = uilabel(app.UIFigure);
            app.TimeoutEditFieldLabel.Text = 'Timeout';
            app.TimeoutEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeoutEditField.Limits = [0 1000];
            app.TimeoutEditField.RoundFractionalValues = 'on';
            app.TimeoutEditField.ValueChangedFcn = createCallbackFcn(app, @TimeoutEditFieldValueChanged, true);
            app.TimeoutEditField.Value = 10;
            app.RestoreDefaultsButton = uibutton(app.UIFigure, 'push');
            app.RestoreDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreDefaultsButtonPushed, true);
            app.RestoreDefaultsButton.Text = 'Restore Defaults';
            app.SaveQuitButton = uibutton(app.UIFigure, 'push');
            app.SaveQuitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveQuitButtonPushed, true);
            app.SaveQuitButton.Text = 'Save & Quit';
            app.MeasurmentstartedmanuallyCheckBox = uicheckbox(app.UIFigure);
            app.MeasurmentstartedmanuallyCheckBox.ValueChangedFcn = createCallbackFcn(app, @MeasurmentstartedmanuallyCheckBoxValueChanged, true);
            app.MeasurmentstartedmanuallyCheckBox.Text = 'Measurment started manually';
            app.PortEditFieldLabel = uilabel(app.UIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.UIFigure, 'text');
            app.PortEditField.ValueChangedFcn = createCallbackFcn(app, @PortEditFieldValueChanged, true);
            app.PortEditField.Value = 'COM12';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Serial_Port_Configuration(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end